classdef DSC_Experiment_UI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        LeftPanel                     matlab.ui.container.Panel
        GridLayout2                   matlab.ui.container.GridLayout
        LoadConfigFileButton          matlab.ui.control.Button
        StartExperimentButton         matlab.ui.control.Button
        PauseExperimentButton         matlab.ui.control.Button
        StopExperimentButton          matlab.ui.control.Button
        ExperimentParametersPanel     matlab.ui.container.Panel
        GridLayout7                   matlab.ui.container.GridLayout
        StartTempCEditFieldLabel      matlab.ui.control.Label
        StartTempCEditField           matlab.ui.control.NumericEditField
        EndTempCEditFieldLabel        matlab.ui.control.Label
        EndTempCEditField             matlab.ui.control.NumericEditField
        RateCminEditFieldLabel        matlab.ui.control.Label
        RateCminEditField             matlab.ui.control.NumericEditField
        HoldTimesecEditFieldLabel     matlab.ui.control.Label
        HoldTimesecEditField          matlab.ui.control.NumericEditField
        SetSerialPortButton           matlab.ui.control.Button
        SerialPortEditFieldLabel      matlab.ui.control.Label
        SerialPortEditField           matlab.ui.control.EditField
        CenterPanel                   matlab.ui.container.Panel
        GridLayout3                   matlab.ui.container.GridLayout
        UIAxes                        matlab.ui.control.UIAxes
        RightPanel                    matlab.ui.container.Panel
        GridLayout4                   matlab.ui.container.GridLayout
        StartTimesecEditFieldLabel    matlab.ui.control.Label
        StartTimesecEditField         matlab.ui.control.NumericEditField
        LatestTimesecEditFieldLabel   matlab.ui.control.Label
        LatestTimesecEditField        matlab.ui.control.NumericEditField
        ElapsedTimesecEditFieldLabel  matlab.ui.control.Label
        ElapsedTimesecEditField       matlab.ui.control.NumericEditField
        TargetTempCEditFieldLabel     matlab.ui.control.Label
        TargetTempCEditField          matlab.ui.control.NumericEditField
        ReferenceSampleLiveDataPanel  matlab.ui.container.Panel
        GridLayout5                   matlab.ui.container.GridLayout
        TemperatureCEditFieldLabel    matlab.ui.control.Label
        TemperatureCEditField         matlab.ui.control.NumericEditField
        CurrentAEditFieldLabel        matlab.ui.control.Label
        CurrentAEditField             matlab.ui.control.NumericEditField
        TestSampleLiveDataPanel       matlab.ui.container.Panel
        GridLayout6                   matlab.ui.container.GridLayout
        TemperatureCEditField_2Label  matlab.ui.control.Label
        TemperatureCEditField_2       matlab.ui.control.NumericEditField
        CurrentAEditField_2Label      matlab.ui.control.Label
        CurrentAEditField_2           matlab.ui.control.NumericEditField
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    
    %   DSC_v2: UI and control systems for prototype DSC system
    %       Copyright (C) 2020  Christian Kunis
    %
    %       This program is free software: you can redistribute it and/or modify
    %       it under the terms of the GNU General Public License as published by
    %       the Free Software Foundation, either version 3 of the License, or
    %       (at your option) any later version.
    %
    %       This program is distributed in the hope that it will be useful,
    %       but WITHOUT ANY WARRANTY; without even the implied warranty of
    %       MERCHANTABILITY or FITNESS FOR A PARTICULAR PURPOSE.  See the
    %       GNU General Public License for more details.
    %
    %       You should have received a copy of the GNU General Public License
    %       along with this program. If not, see <https://www.gnu.org/licenses/>.
    %
    %       You may contact the author at ckunis.contact@gmail.com
    
    properties (Access = private)
        arduino % The serialport object used for communications
        serialPort % The name of the serial port to be used
        serialPortList % The list of available serial ports
        
        ElapsedTimeData = []
        TargetTempData = []
        RefTempData = []
        SampTempData = []
        
        TargetLine % Description
        ReferenceSampleLine % Description
        SampleData % Description
        
        % The number of new samples to wait before attempting to refresh
        % the plots
        REFRESH_COUNTER_THRESHOLD = 1000;
    end
    
    methods (Access = private)
        function receiveControlParameters(app)
            serialData = read(app.arduino, 1, 'char');
            switch serialData
                case 'c'
                    readline(app.arduino);
                    app.StartTempCEditField.Value = double(readline(app.arduino));
                    app.EndTempCEditField.Value = double(readline(app.arduino));
                    app.RateCminEditField.Value = double(readline(app.arduino));
                    app.HoldTimesecEditField.Value = double(readline(app.arduino));
                otherwise
                    disp('Unrecognized value:')
                    disp(serialData)
            end
        end
        
        function receiveSerialData(app)
            startTime = datetime;
            matfileName = ['autoSaveData-',datestr(startTime, 'yyyy_mm_dd_HHMM'),'.mat'];
            
            elapsedTime = zeros(1,1000);
            targetTemp = zeros(1,1000);
            refTemp = zeros(1,1000);
            sampTemp = zeros(1,1000);
            refCurrent = zeros(1,1000);
            sampCurrent = zeros(1,1000);
            refHeatFlow = zeros(1,1000);
            sampHeatFlow = zeros(1,1000);
            refDutyCycle = zeros(1,1000);
            sampDutyCycle = zeros(1,1000);
            
            dataLength = 0;
            
            app.ElapsedTimeData = zeros(1,1000);
            app.TargetTempData = zeros(1,1000);
            app.RefTempData = zeros(1,1000);
            app.SampTempData = zeros(1,1000);
            
            refreshCounter = 0;
            
            experimentIsRunning = true;
            while experimentIsRunning
                serialData = read(app.arduino, 1, 'char');
                
                switch serialData
                    case 's'
                        readline(app.arduino);
                        dataLength = dataLength + 1;
                        elapsedTime(dataLength) = double(readline(app.arduino));
                        targetTemp(dataLength) = double(readline(app.arduino));
                        refTemp(dataLength) = double(readline(app.arduino));
                        sampTemp(dataLength) = double(readline(app.arduino));
                        refCurrent(dataLength) = double(readline(app.arduino));
                        sampCurrent(dataLength) = double(readline(app.arduino));
                        refHeatFlow(dataLength) = double(readline(app.arduino));
                        sampHeatFlow(dataLength) = double(readline(app.arduino));
                        refDutyCycle(dataLength) = double(readline(app.arduino));
                        sampDutyCycle(dataLength) = double(readline(app.arduino));
                        
                        disp(elapsedTime(dataLength)) % DEBUG INFO
                        
                        app.ElapsedTimeData(dataLength) = elapsedTime(dataLength);
                        app.TargetTempData(dataLength) = targetTemp(dataLength);
                        app.RefTempData(dataLength) = refTemp(dataLength);
                        app.SampTempData(dataLength) = sampTemp(dataLength);
                        
                        if refreshCounter < app.REFRESH_COUNTER_THRESHOLD
                            refreshCounter = refreshCounter + 1;
                        else
                            refreshLivePlot(app);
                            refreshCounter = 0;
                        end
                    case 'x'
                        experimentIsRunning = false;
                        disp('Received end signal')
                        setIdleUI(app);
                    otherwise
                        disp('Unrecognized value:')
                        disp(serialData)
                end
            end
            
            save(matfileName, 'startTime', 'elapsedTime', 'targetTemp',...
                'refTemp', 'sampTemp',...
                'refCurrent', 'sampCurrent',...
                'refHeatFlow', 'sampHeatFlow',...
                'refDutyCycle', 'sampDutyCycle', 'dataLength')
        end
        
        function setRunningUI(app)
            app.LoadConfigFileButton.Enable = 'off';
            app.StartExperimentButton.Enable = 'off';
            app.PauseExperimentButton.Enable = 'on';
            app.StopExperimentButton.Enable = 'on';
            app.StartTempCEditField.Editable = 'off';
            app.EndTempCEditField.Editable = 'off';
            app.RateCminEditField.Editable = 'off';
            app.HoldTimesecEditField.Editable = 'off';
            app.SetSerialPortButton.Enable = 'off';
            app.SerialPortEditField.Editable = 'off';
        end
        
        function setIdleUI(app)
            app.LoadConfigFileButton.Enable = 'on';
            app.StartExperimentButton.Enable = 'on';
            app.PauseExperimentButton.Enable = 'off';
            app.StopExperimentButton.Enable = 'off';
            app.StartTempCEditField.Editable = 'on';
            app.EndTempCEditField.Editable = 'on';
            app.RateCminEditField.Editable = 'on';
            app.HoldTimesecEditField.Editable = 'on';
            app.SetSerialPortButton.Enable = 'on';
            app.SerialPortEditField.Editable = 'on';
        end
        
        function refreshLivePlot(app)
            drawnow limitrate nocallbacks
            
            clearpoints(app.TargetLine)
            clearpoints(app.ReferenceSampleLine)
            clearpoints(app.SampleData)
            
            % Update the plots
            addpoints(app.TargetLine, app.ElapsedTimeData ./ 1000, app.TargetTempData)
            addpoints(app.ReferenceSampleLine, app.ElapsedTimeData ./ 1000, app.RefTempData)
            addpoints(app.SampleData, app.ElapsedTimeData ./ 1000, app.SampTempData)
            
            legend(app.UIAxes, 'Location', 'best')
            
            drawnow limitrate
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Get the list of available serial ports
            app.serialPortList = serialportlist("available");
            
            % Set the default serial port to the last port in the list
            app.serialPort = app.serialPortList(end);
            
            % Update the serial port edit field
            app.SerialPortEditField.Value = app.serialPort;
            
            % Create an serial port object where you specify the USB port
            % (look in Arduino->tools -> port and the baud rate (9600)
            app.arduino = serialport(app.serialPort, 9600);
            
            % Request the temperature control parameters from the arduino
            write(app.arduino, 'i', 'char');
            receiveControlParameters(app);
            
            % Create the animatedline objects
            app.TargetLine = animatedline(app.UIAxes, 'Color', 'black', 'LineStyle', ':');
            app.ReferenceSampleLine = animatedline(app.UIAxes, 'Color', 'blue', 'LineStyle', '--');
            app.SampleData = animatedline(app.UIAxes, 'Color', 'red', 'LineStyle', '-.');
            
            % Create a legend for the temperature plot
            legend(app.UIAxes, 'Target Temperature', 'Reference Sample', 'Test Sample', 'Location', 'best')
            
            setIdleUI(app);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {480, 480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x', 220};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end

        % Button pushed function: LoadConfigFileButton
        function LoadConfigFileButtonPushed(app, event)
            write(app.arduino, 'l', 'char');
            
            write(app.arduino, string(app.StartTempCEditField.Value), 'string');
            write(app.arduino, ' ', 'char');
            write(app.arduino, string(app.EndTempCEditField.Value), 'string');
            write(app.arduino, ' ', 'char');
            write(app.arduino, string(app.RateCminEditField.Value), 'string');
            write(app.arduino, ' ', 'char');
            write(app.arduino, string(app.HoldTimesecEditField.Value), 'string');
            
            receiveControlParameters(app);
        end

        % Button pushed function: StartExperimentButton
        function StartExperimentButtonPushed(app, event)
            setRunningUI(app);
            
            write(app.arduino, 's', 'char');
            
            app.receiveSerialData();
        end

        % Button pushed function: PauseExperimentButton
        function PauseExperimentButtonPushed(app, event)
            write(app.arduino, 'p', 'char');
            
        end

        % Button pushed function: StopExperimentButton
        function StopExperimentButtonPushed(app, event)
            setIdleUI(app);
            
            write(app.arduino, 'x', 'char');
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
        end

        % Button pushed function: SetSerialPortButton
        function SetSerialPortButtonPushed(app, event)
            delete(app.arduino);
            if any(contains(serialportlist("available"), app.SerialPortEditField.Value))
                app.serialPort = app.SerialPortEditField.Value;
                
                % Create an serial port object where you specify the USB port
                % (look in Arduino->tools -> port and the baud rate (9600)
                app.arduino = serialport(app.serialPort, 9600);
            else
                warndlg(sprintf("%s is not in the list of available serial ports", app.SerialPortEditField.Value));
                disp(serialportlist("all"))
                disp(app.SerialPortEditField.Value)
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.LeftPanel);
            app.LoadConfigFileButton = uibutton(app.GridLayout2, 'push');
            app.LoadConfigFileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadConfigFileButtonPushed, true);
            app.LoadConfigFileButton.Text = 'Load Config File';
            app.StartExperimentButton = uibutton(app.GridLayout2, 'push');
            app.StartExperimentButton.ButtonPushedFcn = createCallbackFcn(app, @StartExperimentButtonPushed, true);
            app.StartExperimentButton.Text = 'Start Experiment';
            app.PauseExperimentButton = uibutton(app.GridLayout2, 'push');
            app.PauseExperimentButton.ButtonPushedFcn = createCallbackFcn(app, @PauseExperimentButtonPushed, true);
            app.PauseExperimentButton.Text = 'Pause Experiment';
            app.StopExperimentButton = uibutton(app.GridLayout2, 'push');
            app.StopExperimentButton.ButtonPushedFcn = createCallbackFcn(app, @StopExperimentButtonPushed, true);
            app.StopExperimentButton.Text = 'Stop Experiment';
            app.ExperimentParametersPanel = uipanel(app.GridLayout2);
            app.ExperimentParametersPanel.Title = 'Experiment Parameters';
            app.GridLayout7 = uigridlayout(app.ExperimentParametersPanel);
            app.StartTempCEditFieldLabel = uilabel(app.GridLayout7);
            app.StartTempCEditFieldLabel.Text = 'Start Temp (C)';
            app.StartTempCEditField = uieditfield(app.GridLayout7, 'numeric');
            app.EndTempCEditFieldLabel = uilabel(app.GridLayout7);
            app.EndTempCEditFieldLabel.Text = 'End Temp (C)';
            app.EndTempCEditField = uieditfield(app.GridLayout7, 'numeric');
            app.RateCminEditFieldLabel = uilabel(app.GridLayout7);
            app.RateCminEditFieldLabel.Text = 'Rate (C/min)';
            app.RateCminEditField = uieditfield(app.GridLayout7, 'numeric');
            app.HoldTimesecEditFieldLabel = uilabel(app.GridLayout7);
            app.HoldTimesecEditFieldLabel.Text = 'Hold Time (sec)';
            app.HoldTimesecEditField = uieditfield(app.GridLayout7, 'numeric');
            app.SetSerialPortButton = uibutton(app.GridLayout2, 'push');
            app.SetSerialPortButton.ButtonPushedFcn = createCallbackFcn(app, @SetSerialPortButtonPushed, true);
            app.SetSerialPortButton.Text = 'Set Serial Port';
            app.SerialPortEditFieldLabel = uilabel(app.GridLayout2);
            app.SerialPortEditFieldLabel.Text = 'Serial Port';
            app.SerialPortEditField = uieditfield(app.GridLayout2, 'text');
            app.SerialPortEditField.Value = 'COM1';
            app.CenterPanel = uipanel(app.GridLayout);
            app.GridLayout3 = uigridlayout(app.CenterPanel);
            app.UIAxes = uiaxes(app.GridLayout3);
            title(app.UIAxes, 'Temperature vs. Time')
            xlabel(app.UIAxes, 'Time (sec)')
            ylabel(app.UIAxes, 'Temperature (C)')
            app.RightPanel = uipanel(app.GridLayout);
            app.GridLayout4 = uigridlayout(app.RightPanel);
            app.StartTimesecEditFieldLabel = uilabel(app.GridLayout4);
            app.StartTimesecEditFieldLabel.Text = 'Start Time (sec)';
            app.StartTimesecEditField = uieditfield(app.GridLayout4, 'numeric');
            app.StartTimesecEditField.ValueDisplayFormat = '%.3f';
            app.StartTimesecEditField.Editable = 'off';
            app.LatestTimesecEditFieldLabel = uilabel(app.GridLayout4);
            app.LatestTimesecEditFieldLabel.Text = 'Latest Time (sec)';
            app.LatestTimesecEditField = uieditfield(app.GridLayout4, 'numeric');
            app.LatestTimesecEditField.ValueDisplayFormat = '%.3f';
            app.LatestTimesecEditField.Editable = 'off';
            app.ElapsedTimesecEditFieldLabel = uilabel(app.GridLayout4);
            app.ElapsedTimesecEditFieldLabel.Text = 'Elapsed Time (sec)';
            app.ElapsedTimesecEditField = uieditfield(app.GridLayout4, 'numeric');
            app.ElapsedTimesecEditField.ValueDisplayFormat = '%.3f';
            app.ElapsedTimesecEditField.Editable = 'off';
            app.TargetTempCEditFieldLabel = uilabel(app.GridLayout4);
            app.TargetTempCEditFieldLabel.Text = 'Target Temp (C)';
            app.TargetTempCEditField = uieditfield(app.GridLayout4, 'numeric');
            app.TargetTempCEditField.ValueDisplayFormat = '%.3f';
            app.TargetTempCEditField.Editable = 'off';
            app.ReferenceSampleLiveDataPanel = uipanel(app.GridLayout4);
            app.ReferenceSampleLiveDataPanel.Title = 'Reference Sample Live Data';
            app.GridLayout5 = uigridlayout(app.ReferenceSampleLiveDataPanel);
            app.TemperatureCEditFieldLabel = uilabel(app.GridLayout5);
            app.TemperatureCEditFieldLabel.Text = 'Temperature (C)';
            app.TemperatureCEditField = uieditfield(app.GridLayout5, 'numeric');
            app.TemperatureCEditField.ValueDisplayFormat = '%.3f';
            app.TemperatureCEditField.Editable = 'off';
            app.CurrentAEditFieldLabel = uilabel(app.GridLayout5);
            app.CurrentAEditFieldLabel.Text = 'Current (A)';
            app.CurrentAEditField = uieditfield(app.GridLayout5, 'numeric');
            app.CurrentAEditField.ValueDisplayFormat = '%.3f';
            app.CurrentAEditField.Editable = 'off';
            app.TestSampleLiveDataPanel = uipanel(app.GridLayout4);
            app.TestSampleLiveDataPanel.Title = 'Test Sample Live Data';
            app.GridLayout6 = uigridlayout(app.TestSampleLiveDataPanel);
            app.TemperatureCEditField_2Label = uilabel(app.GridLayout6);
            app.TemperatureCEditField_2Label.Text = 'Temperature (C)';
            app.TemperatureCEditField_2 = uieditfield(app.GridLayout6, 'numeric');
            app.TemperatureCEditField_2.ValueDisplayFormat = '%.3f';
            app.TemperatureCEditField_2.Editable = 'off';
            app.CurrentAEditField_2Label = uilabel(app.GridLayout6);
            app.CurrentAEditField_2Label.Text = 'Current (A)';
            app.CurrentAEditField_2 = uieditfield(app.GridLayout6, 'numeric');
            app.CurrentAEditField_2.ValueDisplayFormat = '%.3f';
            app.CurrentAEditField_2.Editable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DSC_Experiment_UI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end